classdef shuiguanv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ProEditFieldLabel            matlab.ui.control.Label
        ProEditField                 matlab.ui.control.NumericEditField
        SEditFieldLabel              matlab.ui.control.Label
        SEditField                   matlab.ui.control.NumericEditField
        DEditFieldLabel              matlab.ui.control.Label
        DEditField                   matlab.ui.control.NumericEditField
        HEditFieldLabel              matlab.ui.control.Label
        HEditField                   matlab.ui.control.NumericEditField
        P1EditFieldLabel             matlab.ui.control.Label
        P1EditField                  matlab.ui.control.NumericEditField
        V2EditFieldLabel             matlab.ui.control.Label
        V2EditField                  matlab.ui.control.NumericEditField
        P2EditFieldLabel             matlab.ui.control.Label
        P2EditField                  matlab.ui.control.NumericEditField
        wherebrokenV1EditFieldLabel  matlab.ui.control.Label
        wherebrokenV1EditField       matlab.ui.control.NumericEditField
        calculateButton              matlab.ui.control.Button
        V1EditFieldLabel             matlab.ui.control.Label
        V1EditField                  matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            p=app.ProEditField.Value;
            s=app.SEditField.Value;
            d=app.DEditField.Value;
            h=app.HEditField.Value;
            p1=app.P1EditField.Value;
            p2=app.P2EditField.Value;
            v1=app.V1EditField.Value;
            k=819501.5915;
            g=9.8;
            A=(3.14159/4)*(d^2);
            sina=s/h;
            h1=(p2+p1+2*p*k*(A^3.5)*(v1^2)+p*g*h)/(2*p*g+4*p*k*(A^3.5)*g);
            x=h1/sina;
            app.wherebrokenV1EditField.Value=x;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '水管漏处计算（相对1）';
            app.ProEditFieldLabel = uilabel(app.UIFigure);
            app.ProEditFieldLabel.Text = 'Pro';
            app.ProEditField = uieditfield(app.UIFigure, 'numeric');
            app.SEditFieldLabel = uilabel(app.UIFigure);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.P1EditFieldLabel = uilabel(app.UIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.UIFigure, 'numeric');
            app.V2EditFieldLabel = uilabel(app.UIFigure);
            app.V2EditFieldLabel.Text = 'V2';
            app.V2EditField = uieditfield(app.UIFigure, 'numeric');
            app.P2EditFieldLabel = uilabel(app.UIFigure);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.UIFigure, 'numeric');
            app.wherebrokenV1EditFieldLabel = uilabel(app.UIFigure);
            app.wherebrokenV1EditFieldLabel.Text = 'where broken(V1)';
            app.wherebrokenV1EditField = uieditfield(app.UIFigure, 'numeric');
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.V1EditFieldLabel = uilabel(app.UIFigure);
            app.V1EditFieldLabel.Text = 'V1';
            app.V1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '（国际单位）';
        end
    end

    methods (Access = public)

        % Construct app
        function app = shuiguanv1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end